classdef SqueezeDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        RightPanel                  matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        SaveManualButton            matlab.ui.control.Button
        ManualEntryEditField        matlab.ui.control.NumericEditField
        ManualEntryLabel            matlab.ui.control.Label
        EditSqueezePanel            matlab.ui.container.Panel
        SaveBadButton               matlab.ui.control.Button
        SaveEditButton              matlab.ui.control.Button
        RightButton                 matlab.ui.control.Button
        LeftButton                  matlab.ui.control.Button
        SaveReviewButton            matlab.ui.control.Button
        NumTrialsBLabel             matlab.ui.control.NumericEditField
        TrialsinBlockLabel          matlab.ui.control.Label
        NumTrialsRLabel             matlab.ui.control.NumericEditField
        TrialsReviewedLabel         matlab.ui.control.Label
        NumTrialsSLabel             matlab.ui.control.NumericEditField
        TrialStartLabel             matlab.ui.control.Label
        BlockLabel                  matlab.ui.control.EditField
        BlockNameLabel              matlab.ui.control.Label
        SubLabel                    matlab.ui.control.EditField
        SubjectIDLabel              matlab.ui.control.Label
        InitialsLabel               matlab.ui.control.EditField
        ReviewerIDLabel             matlab.ui.control.Label
        SummaryLabel                matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        EditButton                  matlab.ui.control.Button
        BadButton                   matlab.ui.control.Button
        GoodButton                  matlab.ui.control.Button
        PlotLabel                   matlab.ui.control.EditField
        PreviousButton              matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        LeftPanel                   matlab.ui.container.Panel
        LoadButton                  matlab.ui.control.Button
        StartTrialNum               matlab.ui.control.NumericEditField
        StartatTrialEditFieldLabel  matlab.ui.control.Label
        Tree                        matlab.ui.container.Tree
        SelectFolderLabel           matlab.ui.control.Label
        ReviewerIDDropDown          matlab.ui.control.DropDown
        ReviewerInitialsLabel       matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        RootDir = "";
        SaveDir = "";
        NodeMap
        Node = NaN;
        IDs = NaN;
        Blocks = NaN;
        Trial = NaN;
        ReachEnd = NaN;
        StabilStart = NaN;
        CorrStart = NaN;
        RevLabel = NaN;
        FilePath = NaN;
        Camera = NaN;
        BlockFolder = "";
        CurrentTrial = NaN;
        Threshold = NaN;
        CurrentIndex = 1;
        Crossings = 1;
        CurrentCross = 1;
        ReviewerID = "";
        SubID = "";
        Block = "";
        TotalTrials = NaN
        TrialsLeft = NaN
        TrialsDone = NaN
        Ndi = NaN;
        PhaseSync = NaN(1,4);
        Label = "";
        PlotTester = NaN;
        ManualSample = NaN;
        TotalSamples = NaN;
    end
    
    methods (Access = private)
        
        function loadTree(app) 
            allFolders = dir(fullfile(app.SaveDir, '**/*'));
            ids = {allFolders.name};
            treeFolders = allFolders([allFolders.isdir] & ~strcmp(ids, '.') & ~strcmp(ids, '..'));
            
            fullDir = string({treeFolders.folder});
            folderName = string({treeFolders.name});
            dirList = strings(0, 3);
            
            for f = 1:length(fullDir)
                substring = split(fullDir(f),'/');
                %disp(substring)
            
                if strcmp(substring(end), 'data')
                    dirList(f,1) = "";
                    dirList(f,2) = folderName(f);
                    dirList(f,3) = fullDir(f) + "/" + folderName(f) + "/";
                else
                    dirList(f,1) = substring(end);
                    dirList(f,2) = folderName(f);
                    dirList(f,3) = fullDir(f) + "/" + folderName(f) + "/";
                end
            end
            
            temp = dirList(:,1);
            dirList(:,1) = dirList(:,2);
            dirList(:,2) = temp;

            % Use a containers.Map to store node handles and quickly look up parents
            app.NodeMap = containers.Map('KeyType', 'char', 'ValueType', 'any');
            
            % Create top-level nodes first
            for i = 1:size(dirList, 1)
                nodeName = dirList(i, 1);
                parentNodeName = dirList(i, 2);
                nodeData = dirList(i, 3);
        
                if strcmp(parentNodeName,"") % Top level node
                    parentNode = app.Tree;
                else
                    % Find parent node handle from the map
                    if isKey(app.NodeMap, parentNodeName)
                        parentNode = app.NodeMap(parentNodeName);
                    else
                        % Handle error if parent not found (or ensure data order is correct)
                        disp(['Parent not found: ', parentNodeName]);
                        continue; 
                    end
                end
        
                % Create the new tree node
                newNode = uitreenode(parentNode, 'Text', nodeName, 'NodeData', nodeData);
                
                % Store the new node handle in the map for future child nodes
                app.NodeMap(nodeName) = newNode;
            end
        end
        
        function updateSummary(app)
            app.InitialsLabel.Value = app.ReviewerID;
            app.SubLabel.Value = app.SubID;
            app.BlockLabel.Value = app.Block;
            app.NumTrialsSLabel.Value = app.StartTrialNum.Value;
            app.NumTrialsRLabel.Value = app.TrialsDone;
            app.NumTrialsBLabel.Value = app.TotalTrials;
        end

        function loadBlock(app)
            folder = app.Node.NodeData;
            listOfFiles = dir(fullfile(folder,'*.mat'));
            trialFiles = string(extractfield(listOfFiles,'name'));
            numTrials = length(listOfFiles);
            triNum = strings(0,1);
            triPath = strings(0,1);
            filePath = strings(0,1);
            
            for t = 1:length(listOfFiles)
                name = trialFiles(t);
                triNum(t) = extractBetween(name, 7, 9);
                triPath(t) = listOfFiles(t).folder + "/" + listOfFiles(t).name;
                filePath(t) = extractBetween(triPath(t),strlength(triPath(t))-44,strlength(triPath(t)));
            end
            
            app.BlockFolder = [str2double(triNum); triPath; filePath]';
            app.TotalTrials = numTrials;
            
            % Names: (1) SubID, (2) Block, (3) Trial, (4) Reach End,
            % (5) Stabilization Start, (6) Correction Start, (7) Trial Label,
            % (8) File Path
            app.IDs = cell(0,1);
            app.Blocks = cell(0,1);
            app.Trial = zeros(0, 1);
            app.ReachEnd = zeros(0, 1);
            app.StabilStart = zeros(0, 1);
            app.CorrStart = zeros(0, 1);
            app.RevLabel = cell(0,1);
            app.FilePath = cell(0,1);
            app.Camera = cell(0,1);
            app.CurrentIndex = length(app.IDs)+1;
        end

        function loadTrial(app)
            % Names: (1) SubID, (2) Block, (3) Trial, (4) Reach End, (5)
            % Stabilization Start, (6) Correction Start
            app.IDs{app.CurrentIndex,1} = app.SubID;
            app.Blocks{app.CurrentIndex,1} = app.Block;
            app.Trial(app.CurrentIndex,1) = app.CurrentTrial;
            app.Label = "";

            % Review photo diode
            clear ballPress photodiode

            app.FilePath{app.CurrentIndex,1} = app.BlockFolder(app.CurrentTrial, 3);
            app.Camera{app.CurrentIndex,1} = 'opti';
            load(app.BlockFolder(app.CurrentTrial, 2), 'daq','stopFlag','phaseSync');

            % If time didn't run out during reach
            if stopFlag ~= -1
                % Check if threshold variable exists for trial
                if ~exist('threshold','var')
                    threshold = 1.8;
                end

                fs = 200;
                cutoff = 10;
                [d1, c1] = butter(4, cutoff/(fs/2), 'low');
                [d2, c2] = butter(4, [59 61]./(fs/2), 'stop');
                ballPress  = filter(d1,c1,daq(:,1));
                photodiode = filter(d2,c2,daq(:,2));
                app.Ndi = [ballPress photodiode];
                phaseSync = floor(phaseSync) - 30;
                app.PhaseSync = phaseSync;

                % Review squeeze ball
                startSec = 1;
                pressure = ballPress(startSec*fs:end);
                app.Threshold = threshold;
                app.TotalSamples = length(ballPress);
                app.ManualEntryEditField.Value = app.TotalSamples;  

                % Find where the logical array changes from 0 to 1
                % diff(is_above) will be 1 at the point of a rising edge
                is_above = pressure >= threshold;
                rising_indices = find(abs(diff(is_above)) == 1);
                app.Crossings = sort(rising_indices) + (startSec*fs) - 1;
                app.CurrentCross = 1;

                % Only save data if not bad
                if isempty(app.Crossings)
                    if exist('phaseSync','var') && stopFlag == 1
                        app.ReachEnd(app.CurrentIndex,1) = phaseSync(2);
                        app.RevLabel{app.CurrentIndex,1} = 'P';
                        app.Crossings = phaseSync(2);
                        if length(app.Crossings) > 2 && ballPress(app.Crossings(1) > app.Crossings(2))
                            app.CurrentCross = 2;
                        end
                    else
                        app.ReachEnd(app.CurrentIndex,1) = NaN;
                        app.RevLabel{app.CurrentIndex,1} = 'B';
                        app.CurrentCross = NaN;
                    end
                else
                    if length(app.Crossings) > 2 && ballPress(app.Crossings(1)) > ballPress(app.Crossings(2))
                        app.CurrentCross = 2;
                    end
                    app.ReachEnd(app.CurrentIndex,1) = app.Crossings(app.CurrentCross);
                    app.RevLabel{app.CurrentIndex,1} = 'G';
                end
            else
                app.ReachEnd(app.CurrentIndex,1) = NaN;
                app.StabilStart(app.CurrentIndex,1) = NaN;
                app.CorrStart(app.CurrentIndex,1) = NaN;
                app.RevLabel{app.CurrentIndex,1} = 'B';
            end

            if strcmp(app.RevLabel{app.CurrentIndex,1}, 'B')
                app.Label = "Nothing above threshold. Select BAD.";
            end
            if stopFlag == 1
                app.Label = "Tester clicked enter to end trial at orange.";
                app.PlotTester = phaseSync(2);
            else 
                app.PlotTester = NaN;
            end
        end

        function plotTrial(app, mode)
            if ~exist('mode','var') 
                mode = '';
            end

            if ~strcmp(mode,'E') 
                app.GoodButton.Enable = "on";
                app.BadButton.Enable = "on";
                app.EditButton.Enable = "on";
            end

            if ~strcmp(app.Label, "")
                app.PlotLabel.Visible = "on";
                app.PlotLabel.Value = app.Label;
            else
                app.PlotLabel.Visible = "off";
            end

            plot(app.UIAxes, app.Ndi(:,1), '-k');
            title(app.UIAxes, ['Trial ' num2str(app.CurrentTrial)])
            xlabel(app.UIAxes, 'time [samples]');
            ylabel(app.UIAxes, 'pressure [V]');
            hold(app.UIAxes, 'on');
            yline(app.UIAxes, app.Threshold, '-r')
            ylim(app.UIAxes, [0 4])
            len = length(app.Ndi); 
            xlim(app.UIAxes, [0 len])

            if ~isnan(app.PlotTester)
                startTest = app.PlotTester - 5;
                stopTest = app.PlotTester + 5;
                xregion(app.UIAxes, startTest, stopTest, 'FaceColor', '#FF8800', 'FaceAlpha', 0.5);
                xline(app.UIAxes, app.PlotTester, 'Color', '#FF8800');
            end

            if strcmp(app.RevLabel{app.CurrentIndex,1} ,'B')
                app.GoodButton.Enable = "off";
                app.EditButton.Enable = "off";
            else       
                if ~isnan(app.ManualSample)
                    startSqz = app.ManualSample;
                    endSqz = app.ManualSample+1;                        
                else
                    startSqz = app.Crossings(app.CurrentCross);
                    if strcmp(app.RevLabel{app.CurrentIndex,1},'P')
                        endSqz = len;
                    else
                        if app.CurrentCross == length(app.Crossings)
                            endSqz = len;
                        else
                            endSqz = app.Crossings(app.CurrentCross + 1);
                        end
                    end
                end

                app.ReachEnd(app.CurrentIndex,1) = startSqz;
                xregion(app.UIAxes, startSqz, endSqz, 'FaceColor', 'cyan', 'FaceAlpha', 0.5);
                xline(app.UIAxes, startSqz,'-b');
            end 

            axis(app.UIAxes, 'on') 
            hold(app.UIAxes, 'off');
        end
    
        function saveTrial(app)
            app.ManualSample = NaN;
            app.ManualEntryEditField.Value = [];   
            squeeze = app.ReachEnd(app.CurrentIndex,1);
            phaseSync = app.PhaseSync;

            if ~strcmp(app.RevLabel{app.CurrentIndex,1} ,'B') && (squeeze + 1) < app.TotalSamples
                if squeeze == phaseSync(2)
                    app.StabilStart(app.CurrentIndex,1) = phaseSync(3);
                    app.CorrStart(app.CurrentIndex, 1) = phaseSync(4);
                else
                    pressure = app.Ndi(:,1);
                    release = squeeze + find( pressure( (squeeze+1):end ) < 1.2, 1, 'first');
                    if isempty(release) || isnan(release) || (release+1) > app.TotalSamples 
                        app.StabilStart(app.CurrentIndex,1) = NaN;
                        app.CorrStart(app.CurrentIndex,1) = NaN;
                    else           
                        app.StabilStart(app.CurrentIndex,1) = release + 1;
    
                        correct = (release+1) + (2*200);
                        if (correct+1) > app.TotalSamples   
                            app.CorrStart(app.CurrentIndex,1) = NaN;
                        else           
                            app.CorrStart(app.CurrentIndex,1) = correct + 1;
                        end
                    end
                end
            else
                app.StabilStart(app.CurrentIndex,1) = NaN;
                app.CorrStart(app.CurrentIndex,1) = NaN;
            end
        end

        function continueNext(app)
            app.CurrentIndex = app.CurrentIndex + 1;
            app.CurrentTrial = app.CurrentTrial + 1;
            app.TrialsDone = app.TrialsDone + 1;
            app.NumTrialsRLabel.Value = app.TrialsDone;
            updateSummary(app);

            app.SaveReviewButton.Enable = "on";

            if app.CurrentTrial == 1
                app.PreviousButton.Enable = "off";
            else
                app.PreviousButton.Enable = "on";
            end

            if app.TrialsDone == app.TrialsLeft
                app.GoodButton.Enable = "off";
                app.GoodButton.BackgroundColor = [0.96,0.96,0.96];
                app.BadButton.Enable = "off";
                app.BadButton.BackgroundColor = [0.96,0.96,0.96];
                app.EditButton.Enable = "off";
                app.EditButton.BackgroundColor = [0.96,0.96,0.96];
                app.SaveReviewButton.BackgroundColor = [0.65,0.96,0.64];
            else
                app.GoodButton.Enable = "on";
                app.GoodButton.BackgroundColor = [0.65,0.96,0.64];
                app.BadButton.Enable = "on";
                app.BadButton.BackgroundColor = [1.00,0.70,0.70];
                app.EditButton.Enable = "on";
                app.EditButton.BackgroundColor = [0.94,0.88,0.59];
                app.SaveReviewButton.BackgroundColor = [0.96,0.96,0.96];

                loadTrial(app);
                plotTrial(app);
            end
        end

        function returnPrevious(app)
            app.CurrentIndex = app.CurrentIndex - 1;
            app.CurrentTrial = app.CurrentTrial - 1;
            app.TrialsDone = app.TrialsDone - 1;
            app.NumTrialsRLabel.Value = app.TrialsDone;
            updateSummary(app);

            app.SaveReviewButton.Enable = "on";

            if app.CurrentTrial == 1
                app.PreviousButton.Enable = "off";
            else
                app.PreviousButton.Enable = "on";
            end

            if app.TrialsDone == app.TrialsLeft
                app.GoodButton.Enable = "off";
                app.GoodButton.BackgroundColor = [0.96,0.96,0.96];
                app.BadButton.Enable = "off";
                app.BadButton.BackgroundColor = [0.96,0.96,0.96];
                app.EditButton.Enable = "off";
                app.EditButton.BackgroundColor = [0.96,0.96,0.96];
                app.SaveReviewButton.BackgroundColor = [0.65,0.96,0.64];
            else
                app.GoodButton.Enable = "on";
                app.GoodButton.BackgroundColor = [0.65,0.96,0.64];
                app.BadButton.Enable = "on";
                app.BadButton.BackgroundColor = [1.00,0.70,0.70];
                app.EditButton.Enable = "on";
                app.EditButton.BackgroundColor = [0.94,0.88,0.59];
                app.SaveReviewButton.BackgroundColor = [0.96,0.96,0.96];

                loadTrial(app);
                plotTrial(app);
            end
        end
    
        function saveReview(app)
             % disp(app.TrialsLeft)
             % disp(app.TrialsDone)
             % disp(['ID: ' num2str(length(app.IDs))])
            % disp(['Blocks: ' num2str(length(app.Blocks))])
            % disp(['Trial: ' num2str(length(app.Trial))])
            % disp(['ReachEnd: ' num2str(length(app.ReachEnd))])
            % disp(['StabilStart: ' num2str(length(app.StabilStart))])
            % disp(['CorrStart: ' num2str(length(app.CorrStart))])
            % disp(['RevLabel: ' num2str(length(app.RevLabel))])
            % disp(['FilePath: ' num2str(length(app.FilePath))])
            % disp(['Camera: ' num2str(length(app.Camera))])
            if length(app.IDs) ~= app.TrialsLeft
                app.IDs = app.IDs(1:app.TrialsDone);
                app.Blocks = app.Blocks(1:app.TrialsDone);
                app.Trial = app.Trial(1:app.TrialsDone);
                app.ReachEnd = app.ReachEnd(1:app.TrialsDone);
                app.StabilStart = app.StabilStart(1:app.TrialsDone);
                app.CorrStart = app.CorrStart(1:app.TrialsDone);
                app.RevLabel = app.RevLabel(1:app.TrialsDone);
                app.FilePath = app.FilePath(1:app.TrialsDone);
                app.Camera = app.Camera(1:app.TrialsDone);
            end
            names = ["SubID" "Block" "Trial" "ReachEnd" "StabilStart" "CorrStart" "RevLabel" "FilePath" "Camera"];
            ReviewTable = table(app.IDs, app.Blocks, app.Trial, app.ReachEnd, app.StabilStart, app.CorrStart, app.RevLabel, app.FilePath, app.Camera, 'VariableNames', names);
            T = readtable([app.RootDir 'SqueezeReviews.xlsx'], 'Sheet', app.ReviewerID);

            if isempty(T)
                T = ReviewTable;
            else
                T = [T; ReviewTable];
            end

            writetable(T,[app.RootDir 'SqueezeReviews.xlsx'], 'Sheet', app.ReviewerID);        
            ReviewTable = table2struct(T);
            save([app.RootDir app.ReviewerID '_SqueezeReviews.mat'], 'ReviewTable');


            app.GoodButton.Enable = "on";
            app.GoodButton.BackgroundColor = [0.65,0.96,0.64];
            app.BadButton.Enable = "on";
            app.BadButton.BackgroundColor = [1.00,0.70,0.70];
            app.EditButton.Enable = "on";
            app.EditButton.BackgroundColor = [0.94,0.88,0.59];
            app.SaveReviewButton.BackgroundColor = [0.96,0.96,0.96];
            app.ReviewerIDDropDown.Enable = "on";
            app.Tree.Enable = "on";
            app.StartTrialNum.Enable = "off";
            app.LoadButton.Enable = "on";
            app.ResetButton.Enable = "off";
            app.CenterPanel.Visible = "off";
            app.RightPanel.Visible = "off";
            app.EditSqueezePanel.Visible = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Fill Folder List
            app.RootDir = [pwd '/'];
            app.SaveDir = [app.RootDir 'data/'];
            loadTree(app);

            app.CenterPanel.Visible = "off";
            app.RightPanel.Visible = "off";
            app.EditSqueezePanel.Visible = "off";
        end

        % Value changed function: ReviewerIDDropDown
        function ReviewerIDDropDownValueChanged(app, event)
            value = app.ReviewerIDDropDown.Value;
            
            if strcmp(value,"Select...") 
                app.CenterPanel.Enable = "off";
                app.RightPanel.Enable = "off";
                app.Tree.Enable = "off";
                app.ReviewerID = "";
            else
                app.CenterPanel.Enable = "on";
                app.Tree.Enable = "on";
                app.ReviewerID = value;
            end
        end

        % Callback function: Tree, Tree
        function TreeDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            isParent = length(node.Children);

            if isParent > 0
                app.CenterPanel.Enable = "off";
                app.RightPanel.Enable = "off";
                app.StartTrialNum.Value = 1;
                app.StartTrialNum.Enable = "off";
                app.LoadButton.Enable = "off";
                app.Node = NaN;
            else
                app.CenterPanel.Enable = "on";
                app.StartTrialNum.Enable = "on";
                app.LoadButton.Enable = "on";
                app.Node = node;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.ReviewerIDDropDown.Enable = "off";
            app.Tree.Enable = "off";
            app.StartTrialNum.Enable = "off";
            app.LoadButton.Enable = "off";
            app.ResetButton.Enable = "on";
            app.RightPanel.Visible = "on";

            app.ReviewerID = app.ReviewerIDDropDown.Value;
            app.CurrentTrial = app.StartTrialNum.Value;
            app.SubID = app.Node.Parent.Text;
            app.Block = app.Node.Text;
            app.TrialsDone = 0;
            app.NumTrialsSLabel.Value = app.StartTrialNum.Value;
            app.NumTrialsRLabel.Value = app.TrialsDone;

            app.CenterPanel.Visible = "on";
            app.RightPanel.Visible = "on";
            app.RightPanel.Enable = "on";
            app.EditSqueezePanel.Visible = "off";

            if app.CurrentTrial > 1
                app.PreviousButton.Enable = "on";
            end

            loadBlock(app);
            updateSummary(app);
            app.TrialsLeft = app.TotalTrials - app.StartTrialNum.Value + 1;
            loadTrial(app);
            plotTrial(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ReviewerID = "";
            app.SubID = "";
            app.Block = "";
            app.ReviewerIDDropDown.Enable = "on";
            app.Tree.Enable = "on";
            app.StartTrialNum.Enable = "off";
            app.LoadButton.Enable = "on";
            app.ResetButton.Enable = "off";
            app.CenterPanel.Visible = "off";
            app.RightPanel.Visible = "off";
            app.EditSqueezePanel.Visible = "off";
        end

        % Button pushed function: GoodButton
        function GoodButtonPushed(app, event)
            saveTrial(app);
            continueNext(app);
        end

        % Button pushed function: BadButton
        function BadButtonPushed(app, event)
            app.ReachEnd(app.CurrentIndex,1) = NaN;
            app.StabilStart(app.CurrentIndex,1) = NaN;
            app.CorrStart(app.CurrentIndex,1) = NaN;
            app.RevLabel{app.CurrentIndex,1} = 'B';

            saveTrial(app);
            continueNext(app);
        end

        % Button pushed function: EditButton
        function EditButton2Pushed(app, event)
            app.RevLabel{app.CurrentIndex,1} = 'E';
            app.EditSqueezePanel.Visible = "on";
            app.EditSqueezePanel.Enable = "on";
            app.LeftButton.Enable = "off";
            if app.CurrentCross == length(app.Crossings)
                app.RightButton.Enable = "off";
            else
                app.RightButton.Enable = "on";
            end
            app.PreviousButton.Enable = "off";
            app.GoodButton.Enable = "off";
            app.GoodButton.BackgroundColor = [0.96,0.96,0.96];
            app.BadButton.Enable = "off";
            app.BadButton.BackgroundColor = [0.96,0.96,0.96];
            app.EditButton.Enable = "off";
            app.EditButton.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: SaveEditButton
        function SaveEditButtonPushed(app, event)
            app.EditSqueezePanel.Visible = "off";
            app.PreviousButton.Enable = "on";
            app.GoodButton.Enable = "on";
            app.GoodButton.BackgroundColor = [0.65,0.96,0.64];
            app.BadButton.Enable = "on";
            app.BadButton.BackgroundColor = [1.00,0.70,0.70];
            app.EditButton.Enable = "on";
            app.EditButton.BackgroundColor = [0.94,0.88,0.59];

            saveTrial(app);
            continueNext(app);
        end

        % Button pushed function: SaveBadButton
        function SaveBadButtonPushed(app, event)
            app.RevLabel{app.CurrentIndex,1} = 'B';
            app.EditSqueezePanel.Visible = "off";
            app.PreviousButton.Enable = "on";
            app.GoodButton.Enable = "on";
            app.GoodButton.BackgroundColor = [0.65,0.96,0.64];
            app.BadButton.Enable = "on";
            app.BadButton.BackgroundColor = [1.00,0.70,0.70];
            app.EditButton.Enable = "on";
            app.EditButton.BackgroundColor = [0.94,0.88,0.59];

            saveTrial(app);
            continueNext(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.SaveReviewButton.BackgroundColor = [0.96,0.96,0.96];
            app.GoodButton.Enable = "on";
            app.GoodButton.BackgroundColor = [0.65,0.96,0.64];
            app.BadButton.Enable = "on";
            app.BadButton.BackgroundColor = [1.00,0.70,0.70];
            app.EditButton.Enable = "on";
            app.EditButton.BackgroundColor = [0.94,0.88,0.59];

            returnPrevious(app);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.CurrentCross = app.CurrentCross - 2;
            app.ManualSample = NaN;
            app.ManualEntryEditField.Value = [];   

            if app.CurrentCross == 1
                app.LeftButton.Enable = "off";
            elseif app.CurrentCross < 1
                app.CurrentCross = 1;
                app.LeftButton.Enable = "off";
            end

            app.RightButton.Enable = "on";
            plotTrial(app,'E');
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.CurrentCross = app.CurrentCross + 2;
            app.ManualSample = NaN;
            app.ManualEntryEditField.Value = [];   
            
            if app.CurrentCross == length(app.Crossings)
                app.RightButton.Enable = "off";
            elseif app.CurrentCross > length(app.Crossings)
                app.CurrentCross = length(app.Crossings);
                app.RightButton.Enable = "off";
            end

            app.LeftButton.Enable = "on";
            plotTrial(app,'E');
        end

        % Button pushed function: SaveReviewButton
        function SaveReviewButtonPushed(app, event)
            saveReview(app);
        end

        % Value changed function: ManualEntryEditField
        function ManualEntryEditFieldValueChanged(app, event)
            value = app.ManualEntryEditField.Value;
            
            if value < 1 || value > app.TotalSamples
                app.ManualSample = NaN;
                app.ManualEntryEditField.Value = app.TotalSamples;  
            end
        end

        % Button pushed function: SaveManualButton
        function SaveManualButtonPushed(app, event)
            app.ManualSample = app.ManualEntryEditField.Value;

            if ~isnan(app.ManualSample)
                app.RevLabel{app.CurrentIndex,1} = 'E';
                app.Label = "Manually edited point";
                plotTrial(app);
                app.RevLabel{app.CurrentIndex,1} = 'E';
                app.EditSqueezePanel.Visible = "on";
                app.EditSqueezePanel.Enable = "on";
                app.LeftButton.Enable = "off";
                app.RightButton.Enable = "off";
                app.PreviousButton.Enable = "off";
                app.GoodButton.Enable = "off";
                app.GoodButton.BackgroundColor = [0.96,0.96,0.96];
                app.BadButton.Enable = "off";
                app.BadButton.BackgroundColor = [0.96,0.96,0.96];
                app.EditButton.Enable = "off";
                app.EditButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {523, 523, 523};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 3;
                app.LeftPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {523, 523};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 222};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.SummaryLabel = uilabel(app.GridLayout3);
            app.SummaryLabel.Text = 'Summary';
            app.ReviewerIDLabel = uilabel(app.GridLayout3);
            app.ReviewerIDLabel.Text = 'Reviewer ID: ';
            app.InitialsLabel = uieditfield(app.GridLayout3, 'text');
            app.InitialsLabel.Enable = 'off';
            app.SubjectIDLabel = uilabel(app.GridLayout3);
            app.SubjectIDLabel.Text = 'Subject ID:  ';
            app.SubLabel = uieditfield(app.GridLayout3, 'text');
            app.SubLabel.Enable = 'off';
            app.BlockNameLabel = uilabel(app.GridLayout3);
            app.BlockNameLabel.Text = 'Block Name:  ';
            app.BlockLabel = uieditfield(app.GridLayout3, 'text');
            app.BlockLabel.Enable = 'off';
            app.TrialStartLabel = uilabel(app.GridLayout3);
            app.TrialStartLabel.Text = '# Trial Start:  ';
            app.NumTrialsSLabel = uieditfield(app.GridLayout3, 'numeric');
            app.NumTrialsSLabel.Enable = 'off';
            app.TrialsReviewedLabel = uilabel(app.GridLayout3);
            app.TrialsReviewedLabel.Text = '# Trials Reviewed:  ';
            app.NumTrialsRLabel = uieditfield(app.GridLayout3, 'numeric');
            app.NumTrialsRLabel.Enable = 'off';
            app.TrialsinBlockLabel = uilabel(app.GridLayout3);
            app.TrialsinBlockLabel.Text = '# Trials in Block:  ';
            app.NumTrialsBLabel = uieditfield(app.GridLayout3, 'numeric');
            app.NumTrialsBLabel.Enable = 'off';
            app.SaveReviewButton = uibutton(app.GridLayout3, 'push');
            app.SaveReviewButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReviewButtonPushed, true);
            app.SaveReviewButton.Enable = 'off';
            app.SaveReviewButton.Text = 'Save';
            app.EditSqueezePanel = uipanel(app.GridLayout3);
            app.EditSqueezePanel.Enable = 'off';
            app.EditSqueezePanel.Title = 'Edit Squeeze Point';
            app.LeftButton = uibutton(app.EditSqueezePanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<-';
            app.RightButton = uibutton(app.EditSqueezePanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '->';
            app.SaveEditButton = uibutton(app.EditSqueezePanel, 'push');
            app.SaveEditButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEditButtonPushed, true);
            app.SaveEditButton.Text = 'Save Edit';
            app.SaveBadButton = uibutton(app.EditSqueezePanel, 'push');
            app.SaveBadButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBadButtonPushed, true);
            app.SaveBadButton.Text = 'Save Bad';
            app.ManualEntryLabel = uilabel(app.GridLayout3);
            app.ManualEntryLabel.Text = 'Manual Entry:  ';
            app.ManualEntryEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ManualEntryEditField.Limits = [0 Inf];
            app.ManualEntryEditField.ValueDisplayFormat = '%.0f';
            app.ManualEntryEditField.AllowEmpty = 'on';
            app.ManualEntryEditField.ValueChangedFcn = createCallbackFcn(app, @ManualEntryEditFieldValueChanged, true);
            app.ManualEntryEditField.Value = [];
            app.SaveManualButton = uibutton(app.GridLayout3, 'push');
            app.SaveManualButton.ButtonPushedFcn = createCallbackFcn(app, @SaveManualButtonPushed, true);
            app.SaveManualButton.Text = 'Save Manual';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Enable = 'off';
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'time [samples]')
            ylabel(app.UIAxes, 'pressure [V]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.PreviousButton = uibutton(app.GridLayout2, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Enable = 'off';
            app.PreviousButton.Text = 'Go to Previous';
            app.PlotLabel = uieditfield(app.GridLayout2, 'text');
            app.PlotLabel.Enable = 'off';
            app.PlotLabel.Visible = 'off';
            app.GoodButton = uibutton(app.GridLayout2, 'push');
            app.GoodButton.ButtonPushedFcn = createCallbackFcn(app, @GoodButtonPushed, true);
            app.GoodButton.Text = 'Good Trial';
            app.BadButton = uibutton(app.GridLayout2, 'push');
            app.BadButton.ButtonPushedFcn = createCallbackFcn(app, @BadButtonPushed, true);
            app.BadButton.Text = 'Bad Trial';
            app.EditButton = uibutton(app.GridLayout2, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButton2Pushed, true);
            app.EditButton.Text = 'Edit Squeeze';
            app.LeftPanel = uipanel(app.GridLayout);
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.ReviewerInitialsLabel = uilabel(app.LeftPanel);
            app.ReviewerInitialsLabel.Text = 'Reviewer ID:';
            app.ReviewerIDDropDown = uidropdown(app.LeftPanel);
            app.ReviewerIDDropDown.Items = {'Select...', '01', '02', '03'};
            app.ReviewerIDDropDown.ValueChangedFcn = createCallbackFcn(app, @ReviewerIDDropDownValueChanged, true);
            app.ReviewerIDDropDown.Value = 'Select...';
            app.SelectFolderLabel = uilabel(app.LeftPanel);
            app.SelectFolderLabel.Text = 'Select Folder:';
            app.Tree = uitree(app.LeftPanel);
            app.Tree.Enable = 'off';
            app.Tree.ClickedFcn = createCallbackFcn(app, @TreeDoubleClicked, true);
            app.Tree.DoubleClickedFcn = createCallbackFcn(app, @TreeDoubleClicked, true);
            app.StartatTrialEditFieldLabel = uilabel(app.LeftPanel);
            app.StartatTrialEditFieldLabel.Text = 'Start at Trial:';
            app.StartTrialNum = uieditfield(app.LeftPanel, 'numeric');
            app.StartTrialNum.Limits = [1 200];
            app.StartTrialNum.ValueDisplayFormat = '%11g';
            app.StartTrialNum.Enable = 'off';
            app.StartTrialNum.Value = 1;
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SqueezeDetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end